classdef node_centralities < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        TableLabel                   matlab.ui.control.Label
        GenerateGraphButton          matlab.ui.control.Button
        CalcCentralityButton         matlab.ui.control.Button
        SelectCentralityDropDown     matlab.ui.control.DropDown
        SelectCentalityDopDownLabel  matlab.ui.control.Label
        NumberOfNodesImportantEditField  matlab.ui.control.NumericEditField
        NumberOfNodesImportantEditFieldLabel  matlab.ui.control.Label
        NumberOfNodesEditField       matlab.ui.control.NumericEditField
        NumberOfNodesEditFieldLabel  matlab.ui.control.Label
        SelectGraphDropDown          matlab.ui.control.DropDown
        SelectGraphDropDownLabel     matlab.ui.control.Label
        delate2                      matlab.ui.control.UIAxes
        delate                       matlab.ui.control.UIAxes
        BlockGenerateGraph           matlab.ui.control.UIAxes
        BlockCalcCentrality          matlab.ui.control.UIAxes
        UIAxesGraph                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        % Color constants for UI elements
        BACKGROUND_COLOR = [0.27, 0.27, 0.27];   % Dark background color
        ENABLED_COLOR = [0.20, 0.69, 0.19];      % Green color for enabled state
        DISABLED_COLOR = [0.69, 0.19, 0.19];     % Red color for disabled state

        % Table and graph configuration constants
        MIN_ROWS = 20;              % Minimum number of rows for display

        % Graph properties
        graphType = '';             % Type of selected network
        n = -1;                     % Total number of nodes in the network
        centralityType = '';        % Selected centrality measure
        m = -1;                     % Number of important nodes based on centrality

        % Graph data structures
        G                           % Graph object
        A                           % Adjacency matrix representation
        p                           % Graph plot handle

    end
    
    methods (Access = private)
        
        function updateGenerateGraphButton(app)
            % Enable/disable graph generation button based on node count
            % Green when valid node count, red when invalid

            if app.NumberOfNodesEditField.Value > 0
                app.GenerateGraphButton.Enable = "on";
                app.GenerateGraphButton.BackgroundColor = app.ENABLED_COLOR;
            else
                app.GenerateGraphButton.Enable = "off";
                app.GenerateGraphButton.BackgroundColor = app.DISABLED_COLOR;
            end
        end

        function updateCalcCentralityButton(app)
            % Enable/disable centrality calculation button 
            % Checks if important node count is valid (>0 and <= total nodes)

            if app.NumberOfNodesImportantEditField.Value > 0 && app.NumberOfNodesImportantEditField.Value <= app.n
                app.CalcCentralityButton.Enable = "on";
                app.CalcCentralityButton.BackgroundColor = app.ENABLED_COLOR;
            else
                app.CalcCentralityButton.Enable = "off";
                app.CalcCentralityButton.BackgroundColor = app.DISABLED_COLOR;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Configure UI Axes components
            configureAxesComponents(app);
            
            % Configure UI Table
            configureTable(app);


            function configureAxesComponents(app)
                % Configure graphical components and background for different UI axes
                % Sets up rounded rectangles, removes axis ticks, and applies background colors

                % Define axes configuration: component and background color pairs
                axesConfig = {
                    app.UIAxesGraph, 'none';  
                    app.BlockCalcCentrality, app.BACKGROUND_COLOR;
                    app.BlockGenerateGraph, app.BACKGROUND_COLOR;
                    app.delate, app.BACKGROUND_COLOR
                };
                
                for i = 1:size(axesConfig, 1)
                    currentAxes = axesConfig{i, 1};
                    faceColor = axesConfig{i, 2};
                    
                    % Get axis ranges and calculate rectangle properties
                    axisRange = [currentAxes.XLim; currentAxes.YLim];
                    rectangleWidth = diff(axisRange, 1, 2).';
                    curvature = currentAxes.PlotBoxAspectRatio([2 1]) * 0.1 * 1.3;
                    
                    % Create rounded rectangle
                    hold(currentAxes, 'on');
                    rectangle(currentAxes, ...
                        'Position', [axisRange(:, 1).', rectangleWidth], ...
                        'Curvature', curvature, ...
                        'FaceColor', faceColor, ...
                        'EdgeColor', faceColor, ...
                        'LineWidth', 0.5);
                    
                    % Configure axes appearance
                    currentAxes.XColor = 'none';
                    currentAxes.YColor = 'none';
                    currentAxes.Color = 'none';
                    currentAxes.XTick = [];
                    currentAxes.YTick = [];
                    grid(currentAxes, 'on');
                    
                    % Adjust axis ticks
                    yt = currentAxes.YTick;
                    if ~isempty(yt)
                        currentAxes.YTick = yt(2:end-1);
                    end
                    
                    xt = currentAxes.XTick;
                    if ~isempty(xt)
                        currentAxes.XTick = xt(2:end-1);
                    end
                    
                    currentAxes.Box = 'off';
                end
            end
        
            function configureTable(app)
                % Set up table formatting and initialize with empty rows
                % Configures column formats, adds headers, and prepares table display

                % Set table column format and style
                app.UITable.ColumnFormat = {'char', 'numeric'};
                tableStyle = uistyle("HorizontalAlignment", "center", "Interpreter", "html");
                addStyle(app.UITable, tableStyle);
                
                % Create and configure headers
                headers = {
                    'Index', [591, 566];
                    'Value', [787, 566]
                };
                
                for i = 1:size(headers, 1)
                    % Create header panel
                    headerPanel = uipanel(app.UIFigure, ...
                        'Position', [headers{i,2}, 196, 40], ...
                        'BackgroundColor', app.BACKGROUND_COLOR);
                    
                    % Create header label
                    headerLabel = uilabel(headerPanel, ...
                        'Text', headers{i,1}, ...
                        'FontWeight', 'bold', ...
                        'FontColor', 'w', ...
                        'FontSize', 16, ...
                        'HorizontalAlignment', 'center', ...
                        'VerticalAlignment', 'center', ...
                        'Position', [0, 0, 196, 40]);
                end
                
                % Initialize table with empty rows
                emptyRow = {'', ''};
                app.UITable.Data = repmat(emptyRow, app.MIN_ROWS, 1);
            end


        end

        % Button pushed function: GenerateGraphButton
        function GenerateGraphButtonPushed(app, event)
            
            % Check if graph needs to be updated
            if strcmp(app.SelectGraphDropDown.Value, app.graphType) && app.NumberOfNodesEditField.Value == app.n
                return;
            end

            % Update graph variables
            app.graphType = app.SelectGraphDropDown.Value;
            app.n = app.NumberOfNodesEditField.Value;
            
            % Validate number of nodes
            if app.n <= 0
                cla(app.UIAxesGraph);
                title(app.UIAxesGraph, '');
                text(app.UIAxesGraph, 0.5, 0.5, errorMessage, ...
                    'FontSize', 16, 'Color', app.DISABLED_COLOR, ...
                    'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
                    'FontWeight', 'bold');
                return;
            end

            % Reset graph display
            cla(app.UIAxesGraph);
            colorbar(app.UIAxesGraph, 'off');
            
            % Disable generate button
            app.GenerateGraphButton.Enable = 'off';
            app.GenerateGraphButton.BackgroundColor = app.DISABLED_COLOR;
            
            % Create adjacency matrix
            switch app.graphType
                case 'erdrey'
                    app.A = erdrey(app.n);
                case 'pref'
                    app.A = pref(app.n);
                case 'smallw'
                    app.A = smallw(app.n);
                otherwise
                    error('Unknown graph type');
            end
            
            % Generate graph
            app.G = graph(app.A);

            % Update centrality button state 
            if app.NumberOfNodesImportantEditField.Value > 0 && app.NumberOfNodesImportantEditField.Value <= app.n
                app.CalcCentralityButton.Enable = 'on';
                app.CalcCentralityButton.BackgroundColor = app.ENABLED_COLOR;
            else
                app.CalcCentralityButton.Enable = 'off';
                app.CalcCentralityButton.BackgroundColor = app.DISABLED_COLOR;
            end
            app.centralityType = '';
            app.m = -1;

            % Plot graph
            title(app.UIAxesGraph, ...
                  sprintf('Graph created with %s', app.graphType), ...
                  'FontSize', 16, ...
                  'Color', 'white');
            app.p = plot(app.UIAxesGraph, app.G);


        end

        % Value changed function: SelectGraphDropDown
        function SelectGraphDropDownValueChanged(app, event)
            
            app.updateGenerateGraphButton();

        end

        % Value changed function: NumberOfNodesEditField
        function NumberOfNodesEditFieldValueChanged(app, event)

            app.updateGenerateGraphButton();
            
        end

        % Value changed function: SelectCentralityDropDown
        function SelectCentralityDropDownValueChanged(app, event)
    
            app.updateCalcCentralityButton();

        end

        % Value changed function: NumberOfNodesImportantEditField
        function NumberOfNodesImportantEditFieldValueChanged(app, event)
            
            app.updateCalcCentralityButton();
            
        end

        % Button pushed function: CalcCentralityButton
        function CalcCentralityButtonPushed(app, event)
            
            % Check if centrality can be calculated
            if strcmp(app.SelectCentralityDropDown.Value, app.centralityType) && app.NumberOfNodesImportantEditField.Value == app.m
                return;
            end
            
            % Update centrality variables
            app.centralityType = app.SelectCentralityDropDown.Value;
            app.m = app.NumberOfNodesImportantEditField.Value;
            
            % Validate the number of nodes
            if app.m <= 0 || app.m > app.n
                % Handle invalid node count (optional)
                return;
            end
            
            % Disable the calculate button
            app.CalcCentralityButton.Enable = "off";
            app.CalcCentralityButton.BackgroundColor = app.DISABLED_COLOR;
            

            % Calculate centrality
            switch app.centralityType
                case 'exponential subgraph'
                    x = exp_sub_centrality(app.G);
                case 'Katz'
                    x = katz_centrality(app.G);
                otherwise
                    x = centrality(app.G, app.centralityType);
            end

            % Sort in descending order (from most important to least)
            [xs, i] = sort(x, 'descend');
    
            % Retrieve the top m most important nodes
            xm = xs(1:app.m, :);
            i = i(1:app.m);


            % Change the table title
            app.TableLabel.Text = sprintf('%d important nodes - %s centrality', app.m, app.centralityType);

            % Update node color data
            app.p.NodeCData = x;

            % Set colorbar
            cmap = flipud(hot()); % Invert the colormap
            cmap = cmap(1:end-30, :); % Remove the black color

            cb = colorbar(app.UIAxesGraph);
            set(cb, 'FontSize', 10);
            colormap(app.UIAxesGraph, cmap);

            %{ 
                Update table
            %}
            data = num2cell([i, xm]);
            
            % Determine how many rows are missing to fill the table
            numRows = size(data, 1);
            missingRows = max(0, app.MIN_ROWS - numRows);
            
            % If there are missing rows, create empty rows with HTML content
            if missingRows > 0
                fillerRow = {'', ''};
                fillerRows = repmat(fillerRow, missingRows, 1);
                data = [data; fillerRows];
            end

            app.UITable.Data = data;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesGraph = uiaxes(app.UIFigure);
            app.UIAxesGraph.XTick = [];
            app.UIAxesGraph.YTick = [];
            app.UIAxesGraph.ZTick = [];
            app.BlockCalcCentrality = uiaxes(app.UIFigure);
            app.BlockCalcCentrality.XTick = [];
            app.BlockCalcCentrality.YTick = [];
            app.BlockCalcCentrality.ZTick = [];
            app.BlockGenerateGraph = uiaxes(app.UIFigure);
            app.BlockGenerateGraph.XTick = [];
            app.BlockGenerateGraph.YTick = [];
            app.BlockGenerateGraph.ZTick = [];
            app.delate = uiaxes(app.UIFigure);
            app.delate.XTick = [];
            app.delate.YTick = [];
            app.delate.ZTick = [];
            app.delate2 = uiaxes(app.UIFigure);
            app.delate2.XTick = [];
            app.delate2.YTick = [];
            app.delate2.ZTick = [];
            app.SelectGraphDropDownLabel = uilabel(app.UIFigure);
            app.SelectGraphDropDownLabel.Text = 'Graph type';
            app.SelectGraphDropDown = uidropdown(app.UIFigure);
            app.SelectGraphDropDown.Items = {'erdrey', 'pref', 'smallw'};
            app.SelectGraphDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectGraphDropDownValueChanged, true);
            app.SelectGraphDropDown.Value = 'erdrey';
            app.NumberOfNodesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfNodesEditFieldLabel.Text = 'Number of nodes';
            app.NumberOfNodesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfNodesEditField.Limits = [0 Inf];
            app.NumberOfNodesEditField.RoundFractionalValues = 'on';
            app.NumberOfNodesEditField.ValueDisplayFormat = '%.0f';
            app.NumberOfNodesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfNodesEditFieldValueChanged, true);
            app.NumberOfNodesImportantEditFieldLabel = uilabel(app.UIFigure);
            app.NumberOfNodesImportantEditFieldLabel.Text = 'Number of important nodes';
            app.NumberOfNodesImportantEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberOfNodesImportantEditField.Limits = [0 Inf];
            app.NumberOfNodesImportantEditField.RoundFractionalValues = 'on';
            app.NumberOfNodesImportantEditField.ValueDisplayFormat = '%.0f';
            app.NumberOfNodesImportantEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfNodesImportantEditFieldValueChanged, true);
            app.SelectCentalityDopDownLabel = uilabel(app.UIFigure);
            app.SelectCentalityDopDownLabel.Text = 'Centrality type';
            app.SelectCentralityDropDown = uidropdown(app.UIFigure);
            app.SelectCentralityDropDown.Items = {'degree', 'closeness', 'betweenness', 'eigenvector', 'pagerank', 'exponential subgraph', 'Katz'};
            app.SelectCentralityDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCentralityDropDownValueChanged, true);
            app.SelectCentralityDropDown.Value = 'degree';
            app.CalcCentralityButton = uibutton(app.UIFigure, 'push');
            app.CalcCentralityButton.ButtonPushedFcn = createCallbackFcn(app, @CalcCentralityButtonPushed, true);
            app.CalcCentralityButton.Enable = 'off';
            app.CalcCentralityButton.Text = 'Calculate centrality';
            app.GenerateGraphButton = uibutton(app.UIFigure, 'push');
            app.GenerateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButtonPushed, true);
            app.GenerateGraphButton.Enable = 'off';
            app.GenerateGraphButton.Text = 'Generate graph';
            app.TableLabel = uilabel(app.UIFigure);
            app.TableLabel.Text = 'Important nodes';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = node_centralities

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end